classdef app_test_eskf_r2017b < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        generatedataButton        matlab.ui.control.Button
        pureintegrationButton     matlab.ui.control.Button
        SigmapositionSliderLabel  matlab.ui.control.Label
        SigmapositionSlider       matlab.ui.control.Slider
        SigmaaccerateSliderLabel  matlab.ui.control.Label
        SigmaaccerateSlider       matlab.ui.control.Slider
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes3                   matlab.ui.control.UIAxes
        UIAxes4                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Sigma_an = 0.01; % noise of an;
        Sigma_sn = 0.1; % noise of sn;
        data_GT = 0;
        data_measure = 0;
        result_integration = 0;
    end
    

    methods (Access = private)

        % Button pushed function: generatedataButton
        function generatedataButtonPushed(app, event)
            [data_GT, data_measure] = generate_data(app.Sigma_an, app.Sigma_sn);
            plot_generate_data(app, data_GT, data_measure);
            app.data_GT = data_GT;
            app.data_measure = data_measure;
        end

        % Value changed function: SigmapositionSlider
        function SigmapositionSliderValueChanged(app, event)
            value = app.SigmapositionSlider.Value;
            app.Sigma_sn = value;
        end

        % Value changed function: SigmaaccerateSlider
        function SigmaaccerateSliderValueChanged(app, event)
            value = app.SigmaaccerateSlider.Value;
            app.Sigma_an = value;
        end

        % Button pushed function: pureintegrationButton
        function pureintegrationButtonPushed(app, event)
            [result_integration] = Integrate(app.data_GT, app.data_measure)
            app.result_integration = result_integration;
            plot_integration(app, app.data_GT, app.result_integration);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.generatedataButton = uibutton(app.UIFigure, 'push');
            app.generatedataButton.ButtonPushedFcn = createCallbackFcn(app, @generatedataButtonPushed, true);
            app.generatedataButton.Text = 'generate data';
            app.pureintegrationButton = uibutton(app.UIFigure, 'push');
            app.pureintegrationButton.ButtonPushedFcn = createCallbackFcn(app, @pureintegrationButtonPushed, true);
            app.pureintegrationButton.Text = 'pure integration';
            app.SigmapositionSliderLabel = uilabel(app.UIFigure);
            app.SigmapositionSliderLabel.Text = 'Sigma position';
            app.SigmapositionSlider = uislider(app.UIFigure);
            app.SigmapositionSlider.Limits = [0 1];
            app.SigmapositionSlider.ValueChangedFcn = createCallbackFcn(app, @SigmapositionSliderValueChanged, true);
            app.SigmaaccerateSliderLabel = uilabel(app.UIFigure);
            app.SigmaaccerateSliderLabel.Text = 'Sigma accerate';
            app.SigmaaccerateSlider = uislider(app.UIFigure);
            app.SigmaaccerateSlider.Limits = [0 1];
            app.SigmaaccerateSlider.ValueChangedFcn = createCallbackFcn(app, @SigmaaccerateSliderValueChanged, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = app_test_eskf_r2017b

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end